classdef PulseWave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OnecycleUIFigure              matlab.ui.Figure
        NumberofWorkersSpinner        matlab.ui.control.Spinner
        NumberofWorkersSpinnerLabel   matlab.ui.control.Label
        ClearListButton               matlab.ui.control.Button
        LoadTextButton                matlab.ui.control.Button
        ExecutefromtextButton         matlab.ui.control.Button
        SHanalysisCheckBox            matlab.ui.control.CheckBox
        FolderManagementButton        matlab.ui.control.Button
        PulsewaveanalysisCheckBox     matlab.ui.control.CheckBox
        ReferenceDirectory            matlab.ui.control.TextArea
        ErrorLabel                    matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        ApplyFlatFieldCheckBox        matlab.ui.control.CheckBox
        ClearButton                   matlab.ui.control.Button
        LoadfolderButton              matlab.ui.control.Button
        ExecuteButton                 matlab.ui.control.Button
        NumberofframesEditField       matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        files = {}
        drawer_list = {}
        flag_is_load
    end

    methods (Access = private)
        function Load(app, path)
       
                for n = 1:length(app.files)
                    if length(app.files)~=app.files{n}.nbFiles 
                        app.ErrorLabel.Text = "Error: number of files not correct";
                        return ; 
                    end 
                end
                app.Lamp.Color = [1, 0, 0];
                drawnow;
                path = strcat(path, '\');
                s = SplashScreen( 'Splashscreen', 'pulsewaves_logo_temp.png','ProgressBar', 'on','ProgressPosition', 5,'ProgressRatio', 0.4 );
                s.addText( 30, 50, 'Git info :', 'FontSize', 15, 'Color', [0 0 0.0] , 'Shadow','off')
                
                
                parfor_arg = app.NumberofWorkersSpinner.Value ;

                gitBranchCommand = 'git symbolic-ref --short HEAD';
                [statusBranch, resultBranch] = system(gitBranchCommand);
    
                if statusBranch == 0
                    resultBranch = strtrim(resultBranch);
                    MessBranch = 'Current branch : ';
                else
                    MessBranch = 'Error getting current branch name. Git command output: ';
                end
    
                gitHashCommand = 'git rev-parse HEAD';
                [statusHash, resultHash] = system(gitHashCommand);
    
                if statusHash == 0 %hash command was successful
                    resultHash = strtrim(resultHash);
                    MessHash = 'Latest Commit Hash : ';
                else
                    MessHash = 'Error getting latest commit hash. Git command output: ';
                end

                % display git info
                s.addText( 30, 80, [MessBranch, resultBranch], 'FontSize', 10, 'Color', [0 0 0] , 'Shadow','off')
                s.addText( 30, 100, [MessHash, resultHash], 'FontSize', 10, 'Color', [0 0 0], 'Shadow','off' )
                

                poolobj = gcp('nocreate'); % check if a pool already exist
                if isempty(poolobj)
                    parpool(parfor_arg); % create a new pool
                elseif poolobj.NumWorkers ~= parfor_arg
                    delete(poolobj); %close the current pool to create a new one with correct num of workers
                    parpool(parfor_arg);
                end

                tic

                try
                    app.files{end + 1} = OneCycleClass(path);
                catch exception
                    disp(['Error while loading : ', path])
                    disp(exception.identifier)
                    disp(exception.message)
                    for i = 1:size(exception.stack,1)
                        stack = sprintf('%s : %s, line : %d',exception.stack(i).file, exception.stack(i).name, exception.stack(i).line);
                        fprintf(stack);
                    end

                end

                %% crop videos
                for n = 1:length(app.files)
                    app.files{n} = app.files{n}.cropAllVideo();
                end


                %% moment normalize
                for n = 1:length(app.files)
                    app.files{n} = app.files{n}.MomentNormalize();
                end

                %% Video resize (preprocess interpolation interpolate)
                app.files{1} = app.files{1}.VideoResize();

                %% interpolate
                app.files{1} = app.files{1}.Interpolate();

                delete(s);

             
                %% End 
                app.LoadfolderButton.Enable = true ;
                app.ExecuteButton.Enable = true ;
                app.ClearButton.Enable = true ;
                % FIXME app.ReferenceDirectory.Value = fullfile(path,file)
                app.ReferenceDirectory.Value = path ;
                disp('Load timing :')
                toc
                delete(s);
                
        end
    end
methods (Access = private)

    function LoadFromTxt(app)

        [selected_file,path] = uigetfile('*.txt');
        if (selected_file)
            fileID = fopen(fullfile(path,selected_file),'r');
            % filecontent = fscanf(fileID,'%s');
            % files_cell = strsplit(filecontent,'\n');

            filecontent = fileread(fullfile(path,selected_file));
            files_cell = strsplit(filecontent,'\n');
            for nn = 1:length(files_cell)
                if ~isempty(files_cell{1,nn})
                    app.drawer_list{end + 1} = files_cell{1,nn};
                end
            end
            fclose(fileID);
        end

    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfolderButton
        function LoadfolderButtonPushed(app, event)
            app.files = {};
            app.ReferenceDirectory.Value = "";
            app.LoadfolderButton.Enable = true;
            app.flag_is_load = false;
            
            clear Parameters_json
            if (app.flag_is_load)
                disp("Files already loaded")
                app.ClearButton.Enable = true ;
            else
                selected_dir = uigetdir();
                app.flag_is_load = true;
                app.Load(selected_dir);
                
            end

            app.ErrorLabel.Text = "" ;
            app.Lamp.Color = [0, 1, 0];
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
           
            %             delete(gcp('nocreate')); % closeparallel CPU pool
            %             parpool;% launch parallel CPU pool

            warning('off');
            parfor_arg = app.NumberofWorkersSpinner.Value ;

            poolobj = gcp('nocreate'); % check if a pool already exist
            if isempty(poolobj)
                parpool(parfor_arg); % create a new pool
            elseif poolobj.NumWorkers ~= parfor_arg
                delete(poolobj); %close the current pool to create a new one with correct num of workers
                parpool(parfor_arg);
            end
            
            clear Parameters_json
            app.Lamp.Color = [1, 0, 0];
            app.ErrorLabel.Text = "" ;
            drawnow;

            for n = 1:length(app.files)
              
                app.files{n}.flag_FlatField = app.ApplyFlatFieldCheckBox.Value ;
                app.files{n}.flag_SH_analysis = app.SHanalysisCheckBox.Value;
                app.files{n}.flag_PulseWave_analysis = app.PulsewaveanalysisCheckBox.Value;
                try
                    app.files{n}.onePulse(app.NumberofframesEditField.Value);
                catch exception
                    fprintf("==============================\nERROR\n==============================\n")
                    disp(['Error with file : ', app.files{n}.directory])
                    disp(exception.identifier)
                    disp(exception.message)
                    for i = 1:size(exception.stack,1)
                        fprintf('%s : %s, line : %d \n',exception.stack(i).file, exception.stack(i).name, exception.stack(i).line);
                    end
                    fprintf("==============================\n")
                end
            end
            app.Lamp.Color = [0, 1, 0];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.files = {};
            app.ReferenceDirectory.Value = "";
            app.LoadfolderButton.Enable = true;
            app.flag_is_load = false;
            
            clear Parameters_json
            
        end

        % Button pushed function: FolderManagementButton
        function FolderManagementButtonPushed(app, event)
            d = dialog('Position', [300, 300, 750, 90 + length(app.drawer_list) * 14],...
                'Color', [0.2, 0.2, 0.2],...
                'Name', 'Folder management',...
                'Resize', 'on',...
                'WindowStyle', 'normal');

            txt = uicontrol('Parent', d,...
                'Style', 'text',...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.2, 0.2, 0.2],...
                'ForegroundColor', [0.8, 0.8, 0.8],...
                'Position', [20, 70, 710, length(app.drawer_list) * 14],...
                'HorizontalAlignment', 'left',...
                'String', app.drawer_list);

            uicontrol('Parent', d,...
                'Position', [20, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Select folder',...
                'Callback', @select);

           uicontrol('Parent', d,...
                'Position', [140, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Select entire folder',...
                'Callback', @select_all);

            uicontrol('Parent', d,...
                'Position', [260, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Clear list',...
                'Callback', @clear_drawer);

            uicontrol('Parent', d,...
                'Position', [380, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Export to text',...
                'Callback', @export_text);


            uicontrol('Parent', d,...
                'Position', [500, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Render',...
                'Callback', @render);


            uiwait(d);

            function select_all(~, ~)
%                 %% selection of one processed folder with uigetdir
%                 selected_dir = uigetdir();
%                 if (selected_dir)
%                     app.drawer_list{end + 1} = selected_dir;
%                 end
%                 txt.String = app.drawer_list;
%                 d.Position(4) = 100 + length(app.drawer_list) * 14;
%                 txt.Position(4) = length(app.drawer_list) * 14;
                
                %% selection of the measurement folder with uigetdir to analyze all processed folders
                selected_dir = uigetdir();
                [~,folder_name,~] = fileparts(selected_dir); 
                % List of Subfolders within the measurement folder
                tmp_dir = dir(selected_dir);
                % remove all files (isdir property is 0)
                subfoldersName = tmp_dir([tmp_dir(:).isdir]);
                % remove '.' and '..' folders
                subfoldersName = subfoldersName(~ismember({subfoldersName(:).name},{'.','..'}));
                subfoldersName = {subfoldersName.name};
                % remove of other folders (ex: 'config' subfolders)
                for ii=1:length(subfoldersName)
                    if contains(subfoldersName{ii},folder_name)
                        app.drawer_list{end + 1} = fullfile(selected_dir,'\',subfoldersName{ii});
                        txt.String = app.drawer_list;
                        d.Position(4) = 100 + length(app.drawer_list) * 14;
                        txt.Position(4) = length(app.drawer_list) * 14;
                    end
                end

            end

            function select(~, ~)
                %% selection of one processed folder with uigetdir
                selected_dir = uigetdir();
                if (selected_dir)
                    app.drawer_list{end + 1} = selected_dir;
                end
                txt.String = app.drawer_list;
                d.Position(4) = 100 + length(app.drawer_list) * 14;
                txt.Position(4) = length(app.drawer_list) * 14;
            end

            function clear_drawer(~, ~)
                app.drawer_list = {};
                txt.String = app.drawer_list;
                d.Position(4) = 100 + length(app.drawer_list) * 14;
                txt.Position(4) = length(app.drawer_list) * 14;
            end

            function export_text(~, ~)
                selected_dir = uigetdir('C:\', 'Select file location');
                filename = fullfile(selected_dir, 'folderManagement.txt');
                fileID = fopen(filename,'a+') ;
                if ~isempty(app.drawer_list)
                    for i = 1:length(app.drawer_list)
                        fprintf(fileID,'%s \n',app.drawer_list{i});
                    end
                end
                
                fclose(fileID);
            end

            function render(~, ~)
                for i = 1:length(app.drawer_list)
                    tic
                    %app.PulsewaveanalysisCheckBox.Value = true;
                    app.Load(app.drawer_list{i});
                    app.ExecuteButtonPushed();
                    app.ClearButtonPushed();
                    toc
                end
%                 clear app.drawer_list
            end
            delete(d);
        end

        % Button pushed function: ExecutefromtextButton
        function ExecutefromtextButtonPushed(app, event)
            app.ClearButtonPushed();
            path_to_copy = uigetdir();
            if ~isempty(app.drawer_list) && ~isempty(path_to_copy)
                for i = 1:length(app.drawer_list)
                    app.Load(app.drawer_list{i});
                    app.ExecuteButtonPushed();
                    mkdir(fullfile(path_to_copy,app.files{1}.ToolBoxmaster.PW_folder_name))
                    copyfile(app.files{1}.ToolBoxmaster.PW_path_dir,fullfile(path_to_copy,app.files{1}.ToolBoxmaster.PW_folder_name))
                    app.ClearButtonPushed();
                end
            end

            % clear app.drawer_list
        end

        % Button pushed function: LoadTextButton
        function LoadTextButtonPushed(app, event)
            app.ClearButtonPushed();
            app.ClearListButtonPushed()
            app.LoadFromTxt()
            app.ClearButton.Enable = true;
        end

        % Button pushed function: ClearListButton
        function ClearListButtonPushed(app, event)
            app.drawer_list = {};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OnecycleUIFigure = uifigure('Visible', 'off');
            app.OnecycleUIFigure.Name = 'One cycle';
            app.NumberofframesEditFieldLabel = uilabel(app.OnecycleUIFigure);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames ';
            app.NumberofframesEditField = uieditfield(app.OnecycleUIFigure, 'numeric');
            app.NumberofframesEditField.Limits = [1 Inf];
            app.NumberofframesEditField.Value = 256;
            app.ExecuteButton = uibutton(app.OnecycleUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.LoadfolderButton = uibutton(app.OnecycleUIFigure, 'push');
            app.LoadfolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfolderButtonPushed, true);
            app.LoadfolderButton.Text = 'Load folder';
            app.ClearButton = uibutton(app.OnecycleUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.ApplyFlatFieldCheckBox = uicheckbox(app.OnecycleUIFigure);
            app.ApplyFlatFieldCheckBox.Text = 'Apply Flat Field';
            app.Lamp = uilamp(app.OnecycleUIFigure);
            app.ErrorLabel = uilabel(app.OnecycleUIFigure);
            app.ErrorLabel.Text = '';
            app.ReferenceDirectory = uitextarea(app.OnecycleUIFigure);
            app.PulsewaveanalysisCheckBox = uicheckbox(app.OnecycleUIFigure);
            app.PulsewaveanalysisCheckBox.Text = 'Pulse wave analysis';
            app.FolderManagementButton = uibutton(app.OnecycleUIFigure, 'push');
            app.FolderManagementButton.ButtonPushedFcn = createCallbackFcn(app, @FolderManagementButtonPushed, true);
            app.FolderManagementButton.Text = 'Folder Management';
            app.SHanalysisCheckBox = uicheckbox(app.OnecycleUIFigure);
            app.SHanalysisCheckBox.Text = 'SH analysis';
            app.ExecutefromtextButton = uibutton(app.OnecycleUIFigure, 'push');
            app.ExecutefromtextButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutefromtextButtonPushed, true);
            app.ExecutefromtextButton.Text = 'Execute from text';
            app.LoadTextButton = uibutton(app.OnecycleUIFigure, 'push');
            app.LoadTextButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTextButtonPushed, true);
            app.LoadTextButton.Text = 'Load Text';
            app.ClearListButton = uibutton(app.OnecycleUIFigure, 'push');
            app.ClearListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearListButtonPushed, true);
            app.ClearListButton.Text = 'Clear List';
            app.NumberofWorkersSpinnerLabel = uilabel(app.OnecycleUIFigure);
            app.NumberofWorkersSpinnerLabel.Text = 'Number of Workers';
            app.NumberofWorkersSpinner = uispinner(app.OnecycleUIFigure);
            app.NumberofWorkersSpinner.Limits = [-1 32];
            app.NumberofWorkersSpinner.Value = 8;
            % Show the figure after all components are created
            app.OnecycleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PulseWave

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OnecycleUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OnecycleUIFigure)
        end
    end
end