classdef Resistivity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResistivityUIFigure           matlab.ui.Figure
        NumberofframesEditFieldLabel  matlab.ui.control.Label
        ARI                           matlab.ui.control.TextArea
        OutputLength                  matlab.ui.control.Label
        FilesDirectory                matlab.ui.control.TextArea
        ClearButton                   matlab.ui.control.Button
        ExecuteButton                 matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        LoadonecyclevideosButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        files = {}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadonecyclevideosButton
        function LoadonecyclevideosButtonPushed(app, event)
            [file,path] = uigetfile('*.avi','Select One Cycle Videos','MultiSelect','on');
            if ~isfloat(file)
                if ~iscell(file)
                    file  = {file};
                end
                app.LoadonecyclevideosButton.Enable = false ; 
                app.Lamp.Color = [1, 0, 0];
                drawnow;
                app.files{end + 1} = OneCycleClass(file,path);
                app.OutputLength.Text = num2str(length(app.files)) ;
                if strcmp(app.FilesDirectory.Value, '')
                    app.FilesDirectory.Value = path ;
                else
                    app.FilesDirectory.Value{end + 1} = path;
                end
            end
            app.LoadonecyclevideosButton.Enable = true ;
            app.Lamp.Color = [0, 1, 0] ;
            app.ExecuteButton.Enable = true ; 
            app.ClearButton.Enable = true ;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.files = {};
            app.OutputLength.Text = num2str(0) ;
            app.FilesDirectory.Value = "";
            app.ARI.Value = "";
            app.LoadonecyclevideosButton.Enable = true;
            app.ExecuteButton.Enable = false ; 
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            app.Lamp.Color = [1, 0, 0];
            drawnow ; 
            for n = 1:length(app.files)
               for k = 1:length(app.files{n}.data)
                   video = app.files{n}.data{k} ; 
                   [RI_map, ARI] = construct_resistivity_index(video) ; 
                   if strcmp(app.ARI.Value, '')
                       app.ARI.Value = num2str(ARI) ;
                   else
                       app.ARI.Value{end + 1} = num2str(ARI);
                   end
               end
            end 
            app.Lamp.Color = [0, 1, 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResistivityUIFigure = uifigure('Visible', 'off');
            colormap(app.ResistivityUIFigure, 'parula');
            app.ResistivityUIFigure.Name = 'Resistivity';
            app.LoadonecyclevideosButton = uibutton(app.ResistivityUIFigure, 'push');
            app.LoadonecyclevideosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadonecyclevideosButtonPushed, true);
            app.LoadonecyclevideosButton.Text = 'Load one cycle videos';
            app.Lamp = uilamp(app.ResistivityUIFigure);
            app.ExecuteButton = uibutton(app.ResistivityUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.ClearButton = uibutton(app.ResistivityUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.FilesDirectory = uitextarea(app.ResistivityUIFigure);
            app.FilesDirectory.Editable = 'off';
            app.OutputLength = uilabel(app.ResistivityUIFigure);
            app.OutputLength.Text = '0';
            app.ARI = uitextarea(app.ResistivityUIFigure);
            app.ARI.Editable = 'off';
            app.NumberofframesEditFieldLabel = uilabel(app.ResistivityUIFigure);
            app.NumberofframesEditFieldLabel.Text = 'Arterial RI mean';
            % Show the figure after all components are created
            app.ResistivityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Resistivity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ResistivityUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResistivityUIFigure)
        end
    end
end