classdef one_cycle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OnecycleUIFigure              matlab.ui.Figure
        FolderManagementButton        matlab.ui.control.Button
        PulsewaveanalysisCheckBox     matlab.ui.control.CheckBox
        Label                         matlab.ui.control.TextArea
        ReferenceDirectory            matlab.ui.control.TextArea
        FilesDirectory                matlab.ui.control.TextArea
        ErrorLabel                    matlab.ui.control.Label
        OutputLength                  matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        ApplytoreferencetooCheckBox   matlab.ui.control.CheckBox
        ClearButton                   matlab.ui.control.Button
        LoadreferenceButton           matlab.ui.control.Button
        LoadfilesButton               matlab.ui.control.Button
        ExecuteButton                 matlab.ui.control.Button
        NumberofframesEditField       matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        files = {}
        reference
        refvideosize
        plot_printed = false
        k = 1 % video interpolation 2^k-1
        drawer_list = {}
    end

    methods (Access = private)
        function LoadReference(app, file, path)
            if ~isfloat(file)
                for n = 1:length(app.files)
                    if length(file)~=app.files{n}.nbFiles 
                        app.ErrorLabel.Text = "Error: number of files not correct";
                        return ; 
                    end 
                end
                app.Lamp.Color = [1, 0, 0];
                drawnow;
                if ~iscell(file)
                    file = {file};
                end
                app.reference = OneCycleClass(file,path);
                app.LoadfilesButton.Enable = true ;
                app.ExecuteButton.Enable = true ;
                app.ClearButton.Enable = true ;
                % FIXME app.ReferenceDirectory.Value = fullfile(path,file)
                app.ReferenceDirectory.Value = path ;
                app.refvideosize = size(app.reference.dataM2M0{1});
            end
        end

        function LoadFiles(app, file, path)
            if ~isfloat(file)
                if ~iscell(file)
                    file  = {file};
                end
                if length(file)~= app.reference.nbFiles
                    app.ErrorLabel.Text = "Error: Number of files not correct" ; 
                    return ; 
                end
                app.LoadfilesButton.Enable = false ; 
                app.Lamp.Color = [1, 0, 0];
                drawnow;
                app.files{end + 1} = OneCycleClass(file,path,app.refvideosize);
                app.OutputLength.Text = num2str(length(app.files)) ;
                if strcmp(app.FilesDirectory.Value, '')
                    app.FilesDirectory.Value = path ;
                else
                    app.FilesDirectory.Value{end + 1} = path;
                end
            end
            %% moment normalize
            for n = 1:length(app.files)
                app.files{n} = app.files{n}.MomentNormalize();
            end

            %% interpolate
            % app.k = 1;
            height = size(app.reference.dataM2M0{1}, 1)*(2^app.k)-app.k;
            width = size(app.reference.dataM2M0{1}, 2)*(2^app.k)-app.k;
            tmp_ref = zeros(height, width, size(app.reference.dataM2M0{1}, 3));
            tmp_dataM2M0 = zeros(height, width, size(app.files{1}.dataM2M0{1}, 3));
            tmp_data_M1M0 = zeros(height, width, size(app.files{1}.dataM2M0{1}, 3));
            tmp_data_SH = zeros(height, width, size(app.files{1}.dataSH{1}, 3));
            tmp_calc_ref = app.reference.dataM2M0{1};
            tmp_calc_data = app.files{1}.dataM2M0{1};
            tmp_calc_data_M1M0 = app.files{1}.dataM1M0{1};
            tmp_calc_data_SH = app.files{1}.dataSH{1};

            num_frames = size(app.reference.dataM2M0{1}, 3);
            k_interp = app.k;

            parfor i = 1 : num_frames % loop over frames
                tmp_ref(:,:,i) = interp2( tmp_calc_ref(:,:,i), k_interp);
                tmp_dataM2M0(:,:,i) = interp2(tmp_calc_data(:,:,i), k_interp);
                tmp_data_M1M0(:,:,i) = interp2(tmp_calc_data_M1M0(:,:,i), k_interp);
            end
            for i = 1 : size(app.files{1}.dataSH{1}, 3)
                tmp_data_SH(:,:,i) = interp2(tmp_calc_data_SH(:,:,i), k_interp);
            end
            app.reference.dataM2M0_interp{1} = tmp_ref;
            app.files{1}.dataM2M0_interp{1} = tmp_dataM2M0;
            app.files{1}.dataM1M0_interp{1} = tmp_data_M1M0;
            app.files{1}.dataSH_interp{1} = tmp_data_SH;
            
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadreferenceButton
        function LoadreferenceButtonPushed(app, event)
            if (isempty(app.reference))
                [file,path] = uigetfile('*.avi;*.raw','Select reference files','MultiSelect','on');
            else
                [file,path] = uigetfile(strcat(app.reference.directory, '*.avi;*.raw'),'Select reference files','MultiSelect','on');
            end

            app.LoadReference(file, path);
            app.ErrorLabel.Text = "" ;
            app.Lamp.Color = [0, 1, 0];
            app.Label.Value = ' ';
        end

        % Button pushed function: LoadfilesButton
        function LoadfilesButtonPushed(app, event)
            
            [file,path] = uigetfile(strcat(app.reference.directory,'*.avi;*.raw'),'Select files','MultiSelect','on');
            
            app.LoadFiles(file, path);
            app.ErrorLabel.Text = "" ; 
            app.LoadfilesButton.Enable = true ;
            app.Lamp.Color = [0, 1, 0] ;
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
           
%             delete(gcp('nocreate')); % closeparallel CPU pool
%             parpool;% launch parallel CPU pool
            
            app.Lamp.Color = [1, 0, 0];
            app.ErrorLabel.Text = "" ;
            drawnow;

            [sys_index_list_cell, mask_cell, maskArtery2, fullPulseWave_cell] = app.reference.getSystole();

            
            if app.ApplytoreferencetooCheckBox.Value 
                app.reference.onePulse(fullPulseWave_cell, mask_cell, maskArtery2, sys_index_list_cell,app.NumberofframesEditField.Value, app.PulsewaveanalysisCheckBox.Value, app.k) ; 
            end
            for n = 1:length(app.files)
                app.files{n}.onePulse(fullPulseWave_cell,mask_cell, maskArtery2, sys_index_list_cell, app.NumberofframesEditField.Value, app.PulsewaveanalysisCheckBox.Value, app.k);
            end
            app.Lamp.Color = [0, 1, 0];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.files = {};
            app.reference = [];
            app.OutputLength.Text = num2str(0) ;
            app.FilesDirectory.Value = "";
            app.ReferenceDirectory.Value = "";
            app.LoadreferenceButton.Enable = true;
            app.LoadfilesButton.Enable = true;
            
        end

        % Button pushed function: FolderManagementButton
        function FolderManagementButtonPushed(app, event)
            d = dialog('Position', [300, 300, 750, 90 + length(app.drawer_list) * 14],...
                'Color', [0.2, 0.2, 0.2],...
                'Name', 'Folder management',...
                'Resize', 'on',...
                'WindowStyle', 'normal');

            txt = uicontrol('Parent', d,...
                'Style', 'text',...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.2, 0.2, 0.2],...
                'ForegroundColor', [0.8, 0.8, 0.8],...
                'Position', [20, 70, 710, length(app.drawer_list) * 14],...
                'HorizontalAlignment', 'left',...
                'String', app.drawer_list);

            uicontrol('Parent', d,...
                'Position', [20, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Select folder',...
                'Callback', @select);

            uicontrol('Parent', d,...
                'Position', [140, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Clear list',...
                'Callback', @clear_drawer);

            uicontrol('Parent', d,...
                'Position', [260, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Render',...
                'Callback', @render);

            uiwait(d);

            function select(~, ~)
                selected_dir = uigetdir();
                if (selected_dir)
                    app.drawer_list{end + 1} = strcat(selected_dir, '\');
                end
                txt.String = app.drawer_list;
                d.Position(4) = 100 + length(app.drawer_list) * 14;
                txt.Position(4) = length(app.drawer_list) * 14;
            end

            function clear_drawer(~, ~)
                app.drawer_list = {};
                txt.String = app.drawer_list;
                d.Position(4) = 100 + length(app.drawer_list) * 14;
                txt.Position(4) = length(app.drawer_list) * 14;
            end

            function render(~, ~)
                ref_file_list = {};
                file_list = {};
                for i = 1:length(app.drawer_list)
                    tmp_idx = regexp(app.drawer_list{i}, '\');
                    name = app.drawer_list{i}(tmp_idx(end-1)+1:end-1);
                    ref_file_list{i} = strcat(name, '_M0.avi');
                    file_list{i} = strcat(name, '_DopplerRMS.raw');
                end
                1;
                for i = 1:length(file_list)
                    tic
                    app.LoadReference(ref_file_list{i}, strcat(app.drawer_list{i}, 'avi'));
                    app.LoadFiles(file_list{i}, strcat(app.drawer_list{i}, 'raw'));
                    app.ExecuteButtonPushed();
                    app.ClearButtonPushed();
                    toc
                end
%                 clear app.drawer_list
            end
            delete(d);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OnecycleUIFigure = uifigure('Visible', 'off');
            app.OnecycleUIFigure.Name = 'One cycle';
            app.NumberofframesEditFieldLabel = uilabel(app.OnecycleUIFigure);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames ';
            app.NumberofframesEditField = uieditfield(app.OnecycleUIFigure, 'numeric');
            app.NumberofframesEditField.Limits = [1 Inf];
            app.NumberofframesEditField.Value = 256;
            app.ExecuteButton = uibutton(app.OnecycleUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.LoadfilesButton = uibutton(app.OnecycleUIFigure, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesButtonPushed, true);
            app.LoadfilesButton.Enable = 'off';
            app.LoadfilesButton.Text = 'Load files';
            app.LoadreferenceButton = uibutton(app.OnecycleUIFigure, 'push');
            app.LoadreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadreferenceButtonPushed, true);
            app.LoadreferenceButton.Text = 'Load reference';
            app.ClearButton = uibutton(app.OnecycleUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.ApplytoreferencetooCheckBox = uicheckbox(app.OnecycleUIFigure);
            app.ApplytoreferencetooCheckBox.Text = 'Apply to reference too';
            app.Lamp = uilamp(app.OnecycleUIFigure);
            app.OutputLength = uilabel(app.OnecycleUIFigure);
            app.OutputLength.Text = '0';
            app.ErrorLabel = uilabel(app.OnecycleUIFigure);
            app.ErrorLabel.Text = '';
            app.FilesDirectory = uitextarea(app.OnecycleUIFigure);
            app.FilesDirectory.Editable = 'off';
            app.ReferenceDirectory = uitextarea(app.OnecycleUIFigure);
            app.Label = uitextarea(app.OnecycleUIFigure);
            app.PulsewaveanalysisCheckBox = uicheckbox(app.OnecycleUIFigure);
            app.PulsewaveanalysisCheckBox.Text = 'Pulse wave analysis';
            app.PulsewaveanalysisCheckBox.Value = true;
            app.FolderManagementButton = uibutton(app.OnecycleUIFigure, 'push');
            app.FolderManagementButton.ButtonPushedFcn = createCallbackFcn(app, @FolderManagementButtonPushed, true);
            app.FolderManagementButton.Text = 'Folder Management';
            % Show the figure after all components are created
            app.OnecycleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = one_cycle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OnecycleUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OnecycleUIFigure)
        end
    end
end