classdef one_cyle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExecutetoreferenceCheckBox   matlab.ui.control.CheckBox
        ClearButton                  matlab.ui.control.Button
        LoadreferenceButton          matlab.ui.control.Button
        ExecuteButton                matlab.ui.control.Button
        NumberofframeEditField       matlab.ui.control.NumericEditField
        NumberofframeEditFieldLabel  matlab.ui.control.Label
        LoadfilesButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        files ={} % Cell of files loaded 

        reference
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfilesButton
        function LoadfilesButtonPushed(app, event)
            [file,path] = uigetfile('*.avi','Select one or more files','MultiSelect','on');

            if ~isfloat(file) 
                if ~iscell(file)
                    file  = {file} ;
                end


                videos = cell(1,length(file)) ;
                for i = 1 : length(file)
                    full_name = fullfile(path,file{i}) ;
                    v = VideoReader(full_name) ;
                    videos{i}.frames = read(v,[1 Inf]) ;
                    videos{i}.filename = file{i};
                end

                fileGroup.filelist = videos ;
                fileGroup.path = path;
                app.files{end + 1} = fileGroup ;

%                 disp(length(app.files)) ;
%                 disp(app.files{1}.filelist{1}.filename) ;
            end 

%             for i = 1:length(app.files)
%                     disp(app.files{i})
%             end
        end

        % Button pushed function: LoadreferenceButton
        function LoadreferenceButtonPushed(app, event)
            
            [file,path] = uigetfile('*.avi','Select one or more files','MultiSelect','on') ; 

            if ~isfloat(file)
                if ~iscell(file) 
                    file = {file} ; 
                end 
    
                videos = cell(1,length(file)) ; 
                for i = 1 : length(file) 
                    full_name = fullfile(path,file{i}) ; 
                    v = VideoReader(full_name) ; 
                    videos{i}.frames = read(v,[1 Inf]) ; 
                    videos{i}.filename = file{i}; 
                end 
    
                fileGroup.filelist = videos ; 
                fileGroup.path = path;
                app.reference = fileGroup ;
                
%                 disp(length(app.files)) ;
%                 disp(app.reference.filelist{1}.filename);
            end 
% 
%             fileGroup.filelist = file;
%             fileGroup.path = path;


        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            disp('Reference files') ; 
            disp('-------------') ; 

            sys_index_list_cell = {};
            for i = 1:length(app.reference.filelist)
                disp(app.reference.filelist{i}.filename);
                video = app.reference.filelist{i}.frames;
                sys_index_list_cell{end + 1} = find_systole_index(video);
            end 


%             for i=1:length(app.reference) 
%                 video = app.reference{i}; 
%                  
%             end 
%             
            Ninterp = app.NumberofframeEditField.Value ; 

            disp('-------------')  ; 
            disp('Files to cut') ; 
            disp('-------------') ; 
            for i = 1:length(app.files) 
                disp(strcat('Group',num2str(i))) ; 
                disp('        ') ; 
                for k = 1:length(app.files{i}.filelist)
                    disp(app.files{i}.filelist{k}.filename) ; 
                end 
                disp('        ') ; 
            end 

% 
%             for i = 1:length(app.files) 
%                 for k = 1 : length(app.files{i}.filelist)
%                     video = app.files{i}.filelist{k} ; 
%                     one_cycle_video = one_cycle_2(video,sys_index_list_cell{k},Ninterp) ;
%                     [path, name, ext] = fileparts(fullfile(app.files{i}.path,app.files{i}.filelist{k}.filename)) ;
%                     full_name= fullfile(path,strcat(name,'_one_cycle',ext)) ;
%                     if (~exist(fullfile(path, 'one_cycle'), 'dir'))
%                         mkdir(fullfile(path, 'one_cycle'));
%                     end 
%                     w = VideoWriter(full_name) ; 
%                     open(w)
%                     for l = 1:size(one_cycle_video,3)
%                         writeVideo(w,one_cycle_video(:,:,i)) ; 
%                     end 
%                     close(w)
% 
%                 end
%             end 


%             for i = 1:length(app.files)
%                 for k = 1:length(app.files{i})
%             
%             video = cell(length(file)) ;
%             Ninterp = app.NumberofframeEditField.Value ; 
%             for i=1:length(file)
%                 video{i} = fullfile(path,file{i});
%                 disp(video{i})
%                 one_cycle_video = one_cycle(video{i}, Ninterp) ;
%                 [path, name, ext] = fileparts(video{i}) ;
%                 full_name= fullfile(path,strcat(name,'_one_cycle',ext)) ; 
% 
%                 w = VideoWriter(full_name) ;
%                 open(w);
%                 for i = 1:size(one_cycle_video,3)
%                     writeVideo(w, one_cycle_video(:,:,i));
%                 end
%                 close(w) ;
%             end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.files = {} ; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadfilesButton = uibutton(app.UIFigure, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesButtonPushed, true);
            app.LoadfilesButton.Text = 'Load files';
            app.NumberofframeEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofframeEditFieldLabel.Text = 'Number of frame ';
            app.NumberofframeEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofframeEditField.Value = 64;
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.LoadreferenceButton = uibutton(app.UIFigure, 'push');
            app.LoadreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadreferenceButtonPushed, true);
            app.LoadreferenceButton.Text = 'Load reference';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ExecutetoreferenceCheckBox = uicheckbox(app.UIFigure);
            app.ExecutetoreferenceCheckBox.Text = 'Execute to reference';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = one_cyle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end